classdef lauch_control_v1_rejeu_log < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        NODATALabel               matlab.ui.control.Label
        Rejeux30Label             matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        HeurelogsTLabel           matlab.ui.control.Label
        HeureLabel                matlab.ui.control.Label
        ConnexionSerialLamp       matlab.ui.control.Lamp
        ConnexionSerialLampLabel  matlab.ui.control.Label
        CONTRLEMISSIONLabel       matlab.ui.control.Label
        LSM9DS1Label              matlab.ui.control.Label
        GyrZGauge                 matlab.ui.control.LinearGauge
        GyrZGaugeLabel            matlab.ui.control.Label
        GyrYGauge                 matlab.ui.control.LinearGauge
        GyrYGaugeLabel            matlab.ui.control.Label
        GyrXGauge                 matlab.ui.control.LinearGauge
        GyrXGaugeLabel            matlab.ui.control.Label
        MagZGauge                 matlab.ui.control.LinearGauge
        MagZGaugeLabel            matlab.ui.control.Label
        MagYGauge                 matlab.ui.control.LinearGauge
        MagYGaugeLabel            matlab.ui.control.Label
        MagXGauge                 matlab.ui.control.LinearGauge
        MagXGaugeLabel            matlab.ui.control.Label
        AccZGauge                 matlab.ui.control.LinearGauge
        AccZGaugeLabel            matlab.ui.control.Label
        AccYGauge                 matlab.ui.control.LinearGauge
        AccYGaugeLabel            matlab.ui.control.Label
        AccxGauge                 matlab.ui.control.LinearGauge
        AccxGaugeLabel            matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        Label                     matlab.ui.control.Label
        BUS0INTRIEURLabel         matlab.ui.control.Label
        BUS7EXTRIEURLabel         matlab.ui.control.Label
        BUS1EXTRIEURLabel         matlab.ui.control.Label
        Temprature7bmpGauge       matlab.ui.control.Gauge
        Temprature7bmpGaugeLabel  matlab.ui.control.Label
        Pression7Gauge            matlab.ui.control.Gauge
        Pression7GaugeLabel       matlab.ui.control.Label
        Temprature7Gauge          matlab.ui.control.Gauge
        Temprature7GaugeLabel     matlab.ui.control.Label
        Humidit7Gauge             matlab.ui.control.Gauge
        Humidit7GaugeLabel        matlab.ui.control.Label
        VOCIndex7Gauge            matlab.ui.control.Gauge
        VOCIndex7GaugeLabel       matlab.ui.control.Label
        Altitude7Altimeter        Aero.ui.control.Altimeter
        Altitude7AltimeterLabel   matlab.ui.control.Label
        Temprature1bmpGauge       matlab.ui.control.Gauge
        Temprature1bmpGaugeLabel  matlab.ui.control.Label
        Pression1Gauge            matlab.ui.control.Gauge
        Pression1GaugeLabel       matlab.ui.control.Label
        Temprature1Gauge          matlab.ui.control.Gauge
        Temprature1GaugeLabel     matlab.ui.control.Label
        Humidit1Gauge             matlab.ui.control.Gauge
        Humidit1GaugeLabel        matlab.ui.control.Label
        VOCIndex1Gauge            matlab.ui.control.Gauge
        VOCIndex1GaugeLabel       matlab.ui.control.Label
        Altitude1Altimeter        Aero.ui.control.Altimeter
        Altitude1AltimeterLabel   matlab.ui.control.Label
        TensionGauge              matlab.ui.control.Gauge
        TensionGaugeLabel         matlab.ui.control.Label
        LuxmtreGauge              matlab.ui.control.Gauge
        LuxmtreGaugeLabel         matlab.ui.control.Label
        Temprature0bmpGauge       matlab.ui.control.Gauge
        Temprature0bmpGaugeLabel  matlab.ui.control.Label
        Pression0Gauge            matlab.ui.control.Gauge
        Pression0GaugeLabel       matlab.ui.control.Label
        Temprature0Gauge          matlab.ui.control.Gauge
        Temprature0GaugeLabel     matlab.ui.control.Label
        Humidit0Gauge             matlab.ui.control.Gauge
        Humidit0GaugeLabel        matlab.ui.control.Label
        VOCIndex0Gauge            matlab.ui.control.Gauge
        VOCIndex0GaugeLabel       matlab.ui.control.Label
        Altitude0Altimeter        Aero.ui.control.Altimeter
        Altitude0AltimeterLabel   matlab.ui.control.Label
    end

    
  properties (Access = public)
    running = 0; % Indique si l'acquisition est en cours
    serialObj; % Objet de communication série
    dataBuffer; % Stocke les données brutes
    timerObj                    % Timer pour simuler la lecture en continu
    logLines                    % Contenu du fichier log
    currentLine = 1             % Index de la ligne en cours
    cubeOriginalVerts  % Stocke les coordonnées d'origine du cube
end

methods (Access = public)

        % Chargement du fichier de log
        function loadLogFile(app, filename)
            rawData = fileread(filename);
            app.logLines = strsplit(rawData, ';'); % Split à chaque trame
            app.currentLine = 1;
            disp("Fichier log chargé : " + filename);
        end


        % Lecture simulée des données ligne par ligne
        function readSerialData(app, ~, ~)
        if app.currentLine > length(app.logLines)
            % Redémarrer depuis le début
            app.currentLine = 1;
            disp("Redémarrage du fichier log...");
        end
    
        newData = strtrim(app.logLines{app.currentLine});
        app.currentLine = app.currentLine + 1;
    
        if newData == ""
            return;
        end
    
        %disp("Lecture trame : " + newData);
    
        try
            values = strsplit(newData, ",");
            if length(values) >= 1
                app.HeureLabel.Text = values{1};
                app.NODATALabel.Visible = "off";
                if strcmp(values{1},"xxxxmxxs")
                    app.NODATALabel.Visible = "on";
                    pause(3000);
                end
            end
    
            if length(values) >= 33
                app.Humidit0Gauge.Value = str2double(values{2});
                app.Temprature0Gauge.Value = str2double(values{3});
                app.VOCIndex0Gauge.Value = str2double(values{5});
                app.Pression0Gauge.Value = str2double(values{6});
                app.Temprature0bmpGauge.Value = str2double(values{7});
                app.Altitude0Altimeter.Value = str2double(values{8}) * 3.28084;
                app.LuxmtreGauge.Value = str2double(values{9});
                app.AccxGauge.Value = str2double(values{10});
                app.AccYGauge.Value = str2double(values{11});
                app.AccZGauge.Value = str2double(values{12});
                app.MagXGauge.Value = str2double(values{13});
                app.MagYGauge.Value = str2double(values{14});
                app.MagZGauge.Value = str2double(values{15});
                app.GyrXGauge.Value = str2double(values{16});
                app.GyrYGauge.Value = str2double(values{17});
                app.GyrZGauge.Value = str2double(values{18});
                app.TensionGauge.Value = str2double(values{19});
                app.Humidit1Gauge.Value = str2double(values{20});
                app.Temprature1Gauge.Value = str2double(values{21});
                app.VOCIndex1Gauge.Value = str2double(values{23});
                app.Pression1Gauge.Value = str2double(values{24});
                app.Temprature1bmpGauge.Value = str2double(values{25});
                app.Altitude1Altimeter.Value = str2double(values{26}) * 3.28084;
                app.Humidit7Gauge.Value = str2double(values{27});
                app.Temprature7Gauge.Value = str2double(values{28});
                app.VOCIndex7Gauge.Value = str2double(values{30});
                app.Pression7Gauge.Value = str2double(values{31});
                app.Temprature7bmpGauge.Value = str2double(values{32});
                app.Altitude7Altimeter.Value = str2double(values{33}) * 3.28084;
               
            end
    
        catch
            disp("Erreur de lecture !");
        end
    end
end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
      try
                app.loadLogFile("log_test_interpolated_cropped.txt"); % version échantillonée à 3Hz avec interpolation linéaire entre les logs initiaux
                 
                app.timerObj = timer(...
                    'ExecutionMode', 'fixedSpacing', ...
                    'Period', 0.01, ... % Rejeu à vitesse x30 (fréquence d'échantillonage de 3Hz)
                    'TimerFcn', @(~,~) app.readSerialData([], []), ...
                    'StartDelay', 1);

                start(app.timerObj);

                % Lancement animation python
                scriptDir = fileparts(mfilename('fullpath'));  % Dossier de l'app mlapp
                scriptPath = fullfile(scriptDir, 'plot_kikiwi_traj.py');
                command = sprintf('start /B py "%s"', scriptPath);
                system(command);

                disp("Lecture simulée activée !");
            catch
                disp("Erreur lors du démarrage !");
      end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Altitude0AltimeterLabel = uilabel(app.UIFigure);
            app.Altitude0AltimeterLabel.Text = 'Altitude0';
            app.Altitude0Altimeter = uiaeroaltimeter(app.UIFigure);
            app.VOCIndex0GaugeLabel = uilabel(app.UIFigure);
            app.VOCIndex0GaugeLabel.Text = 'VOC Index0';
            app.VOCIndex0Gauge = uigauge(app.UIFigure, 'circular');
            app.VOCIndex0Gauge.Limits = [0 600];
            app.Humidit0GaugeLabel = uilabel(app.UIFigure);
            app.Humidit0GaugeLabel.Text = 'Humidité0';
            app.Humidit0Gauge = uigauge(app.UIFigure, 'circular');
            app.Temprature0GaugeLabel = uilabel(app.UIFigure);
            app.Temprature0GaugeLabel.Text = 'Température0';
            app.Temprature0Gauge = uigauge(app.UIFigure, 'circular');
            app.Temprature0Gauge.Limits = [-60 60];
            app.Temprature0Gauge.MajorTicks = [-60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60];
            app.Temprature0Gauge.MajorTickLabels = {'-60', '-50', '-40', '-30', '-20', '-10', '0', '10', '20', '30', '40', '50', '60'};
            app.Pression0GaugeLabel = uilabel(app.UIFigure);
            app.Pression0GaugeLabel.Text = 'Pression0';
            app.Pression0Gauge = uigauge(app.UIFigure, 'circular');
            app.Pression0Gauge.Limits = [0 1030];
            app.Temprature0bmpGaugeLabel = uilabel(app.UIFigure);
            app.Temprature0bmpGaugeLabel.Text = 'Température0 (bmp)';
            app.Temprature0bmpGauge = uigauge(app.UIFigure, 'circular');
            app.Temprature0bmpGauge.Limits = [-60 60];
            app.LuxmtreGaugeLabel = uilabel(app.UIFigure);
            app.LuxmtreGaugeLabel.Text = 'Luxmètre';
            app.LuxmtreGauge = uigauge(app.UIFigure, 'circular');
            app.LuxmtreGauge.Limits = [0 1000];
            app.TensionGaugeLabel = uilabel(app.UIFigure);
            app.TensionGaugeLabel.Text = 'Tension';
            app.TensionGauge = uigauge(app.UIFigure, 'circular');
            app.TensionGauge.Limits = [0 35];
            app.Altitude1AltimeterLabel = uilabel(app.UIFigure);
            app.Altitude1AltimeterLabel.Text = 'Altitude1';
            app.Altitude1Altimeter = uiaeroaltimeter(app.UIFigure);
            app.VOCIndex1GaugeLabel = uilabel(app.UIFigure);
            app.VOCIndex1GaugeLabel.Text = 'VOC Index1';
            app.VOCIndex1Gauge = uigauge(app.UIFigure, 'circular');
            app.VOCIndex1Gauge.Limits = [0 600];
            app.Humidit1GaugeLabel = uilabel(app.UIFigure);
            app.Humidit1GaugeLabel.Text = 'Humidité1';
            app.Humidit1Gauge = uigauge(app.UIFigure, 'circular');
            app.Temprature1GaugeLabel = uilabel(app.UIFigure);
            app.Temprature1GaugeLabel.Text = 'Température1';
            app.Temprature1Gauge = uigauge(app.UIFigure, 'circular');
            app.Temprature1Gauge.Limits = [-60 60];
            app.Pression1GaugeLabel = uilabel(app.UIFigure);
            app.Pression1GaugeLabel.Text = 'Pression1';
            app.Pression1Gauge = uigauge(app.UIFigure, 'circular');
            app.Pression1Gauge.Limits = [0 1030];
            app.Temprature1bmpGaugeLabel = uilabel(app.UIFigure);
            app.Temprature1bmpGaugeLabel.Text = 'Température1 (bmp)';
            app.Temprature1bmpGauge = uigauge(app.UIFigure, 'circular');
            app.Temprature1bmpGauge.Limits = [-60 60];
            app.Altitude7AltimeterLabel = uilabel(app.UIFigure);
            app.Altitude7AltimeterLabel.Text = 'Altitude7';
            app.Altitude7Altimeter = uiaeroaltimeter(app.UIFigure);
            app.VOCIndex7GaugeLabel = uilabel(app.UIFigure);
            app.VOCIndex7GaugeLabel.Text = 'VOC Index7';
            app.VOCIndex7Gauge = uigauge(app.UIFigure, 'circular');
            app.VOCIndex7Gauge.Limits = [0 600];
            app.Humidit7GaugeLabel = uilabel(app.UIFigure);
            app.Humidit7GaugeLabel.Text = 'Humidité7';
            app.Humidit7Gauge = uigauge(app.UIFigure, 'circular');
            app.Temprature7GaugeLabel = uilabel(app.UIFigure);
            app.Temprature7GaugeLabel.Text = 'Température7';
            app.Temprature7Gauge = uigauge(app.UIFigure, 'circular');
            app.Temprature7Gauge.Limits = [-60 60];
            app.Pression7GaugeLabel = uilabel(app.UIFigure);
            app.Pression7GaugeLabel.Text = 'Pression7';
            app.Pression7Gauge = uigauge(app.UIFigure, 'circular');
            app.Pression7Gauge.Limits = [0 1030];
            app.Temprature7bmpGaugeLabel = uilabel(app.UIFigure);
            app.Temprature7bmpGaugeLabel.Text = 'Température7 (bmp)';
            app.Temprature7bmpGauge = uigauge(app.UIFigure, 'circular');
            app.Temprature7bmpGauge.Limits = [-60 60];
            app.BUS1EXTRIEURLabel = uilabel(app.UIFigure);
            app.BUS1EXTRIEURLabel.Text = '--------------------------------------------------------------------------------------------------- BUS 1 [EXTÉRIEUR] ---------------------------------------------------------------------------------------------------';
            app.BUS7EXTRIEURLabel = uilabel(app.UIFigure);
            app.BUS7EXTRIEURLabel.Text = '------------------------------------------------------------------------------------------------------------- BUS 7 [EXTÉRIEUR] ----------------------------------------------------------------------------------------';
            app.BUS0INTRIEURLabel = uilabel(app.UIFigure);
            app.BUS0INTRIEURLabel.Text = '------------------------------------------------------------------------------------------------------ BUS 0 [INTÉRIEUR] ------------------------------------------------------------------------------------------------';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; ''; ''; ''};
            app.AccxGaugeLabel = uilabel(app.UIFigure);
            app.AccxGaugeLabel.Text = 'Acc x';
            app.AccxGauge = uigauge(app.UIFigure, 'linear');
            app.AccxGauge.Limits = [-30 30];
            app.AccYGaugeLabel = uilabel(app.UIFigure);
            app.AccYGaugeLabel.Text = 'Acc Y';
            app.AccYGauge = uigauge(app.UIFigure, 'linear');
            app.AccYGauge.Limits = [-30 30];
            app.AccZGaugeLabel = uilabel(app.UIFigure);
            app.AccZGaugeLabel.Text = 'Acc Z';
            app.AccZGauge = uigauge(app.UIFigure, 'linear');
            app.AccZGauge.Limits = [-30 30];
            app.MagXGaugeLabel = uilabel(app.UIFigure);
            app.MagXGaugeLabel.Text = 'Mag X';
            app.MagXGauge = uigauge(app.UIFigure, 'linear');
            app.MagXGauge.Limits = [-100 100];
            app.MagYGaugeLabel = uilabel(app.UIFigure);
            app.MagYGaugeLabel.Text = 'Mag Y';
            app.MagYGauge = uigauge(app.UIFigure, 'linear');
            app.MagYGauge.Limits = [-100 100];
            app.MagZGaugeLabel = uilabel(app.UIFigure);
            app.MagZGaugeLabel.Text = 'Mag Z';
            app.MagZGauge = uigauge(app.UIFigure, 'linear');
            app.MagZGauge.Limits = [-100 100];
            app.GyrXGaugeLabel = uilabel(app.UIFigure);
            app.GyrXGaugeLabel.Text = 'Gyr X';
            app.GyrXGauge = uigauge(app.UIFigure, 'linear');
            app.GyrXGauge.Limits = [-30 30];
            app.GyrYGaugeLabel = uilabel(app.UIFigure);
            app.GyrYGaugeLabel.Text = 'Gyr Y';
            app.GyrYGauge = uigauge(app.UIFigure, 'linear');
            app.GyrYGauge.Limits = [-30 30];
            app.GyrZGaugeLabel = uilabel(app.UIFigure);
            app.GyrZGaugeLabel.Text = 'Gyr Z';
            app.GyrZGauge = uigauge(app.UIFigure, 'linear');
            app.GyrZGauge.Limits = [-30 30];
            app.LSM9DS1Label = uilabel(app.UIFigure);
            app.LSM9DS1Label.Text = 'LSM9DS1';
            app.CONTRLEMISSIONLabel = uilabel(app.UIFigure);
            app.CONTRLEMISSIONLabel.Text = 'CONTRÔLE MISSION';
            app.ConnexionSerialLampLabel = uilabel(app.UIFigure);
            app.ConnexionSerialLampLabel.Text = 'Connexion Serial';
            app.ConnexionSerialLamp = uilamp(app.UIFigure);
            app.HeureLabel = uilabel(app.UIFigure);
            app.HeureLabel.Text = 'Heure ';
            app.HeurelogsTLabel = uilabel(app.UIFigure);
            app.HeurelogsTLabel.Text = '       Heure logs : T + ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'LOGO_raph_v01_rvb.png');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '------------------------------------------------------------------------------------------------------                                     ------------------------------------------------------------------------------------------------';
            app.Rejeux30Label = uilabel(app.UIFigure);
            app.Rejeux30Label.Text = '(Rejeu x30)';
            app.NODATALabel = uilabel(app.UIFigure);
            app.NODATALabel.Text = 'NO DATA';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lauch_control_v1_rejeu_log

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end